classdef Data_Acquisition_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcousticPlateReaderUIFigure     matlab.ui.Figure
        StopScanReturntoOriginButton    matlab.ui.control.Button
        NEquilibrationFrames            matlab.ui.container.Panel
        EquilibrationFramesEditField    matlab.ui.control.NumericEditField
        NumberofEquilibrationFramesLabel  matlab.ui.control.Label
        ReplicateParameters_2           matlab.ui.container.Panel
        Numberofcolumnsofplates         matlab.ui.control.NumericEditField
        NumberofcolumnsEditField_2Label_2  matlab.ui.control.Label
        Numberofrowsofplates            matlab.ui.control.NumericEditField
        NumberofrowsLabel_2             matlab.ui.control.Label
        MultiplePlateParametersLabel    matlab.ui.control.Label
        Startcolumnofplates             matlab.ui.control.NumericEditField
        StartingcolumnLabel             matlab.ui.control.Label
        Startrowofplates                matlab.ui.control.NumericEditField
        StartingrowLabel                matlab.ui.control.Label
        TransducerButtonGroup           matlab.ui.container.ButtonGroup
        L2214vXButton                   matlab.ui.control.RadioButton
        L104vButton                     matlab.ui.control.RadioButton
        L2214vButton                    matlab.ui.control.RadioButton
        BeamTypeButtonGroup             matlab.ui.container.ButtonGroup
        xAMButton                       matlab.ui.control.RadioButton
        pAMButton                       matlab.ui.control.RadioButton
        xBmodeButton                    matlab.ui.control.RadioButton
        pBmodeButton                    matlab.ui.control.RadioButton
        PlateParameters                 matlab.ui.container.Panel
        IndividualPlateParametersLabel  matlab.ui.control.Label
        StartingwellEditField           matlab.ui.control.EditField
        StartingwellEditFieldLabel      matlab.ui.control.Label
        NumberofcolumnsEditField        matlab.ui.control.NumericEditField
        NumberofcolumnsEditFieldLabel   matlab.ui.control.Label
        NumberofrowsEditField           matlab.ui.control.NumericEditField
        NumberofrowsLabel               matlab.ui.control.Label
        SaveFileDirectory               matlab.ui.container.Panel
        UploadpresetButton              matlab.ui.control.Button
        SavepresetButton                matlab.ui.control.Button
        Vantage440462supportedLabel     matlab.ui.control.Label
        SelectVantagedirectoryButton    matlab.ui.control.Button
        SavenameEditField               matlab.ui.control.EditField
        SavenameLabel                   matlab.ui.control.Label
        SaveInfoLabel                   matlab.ui.control.Label
        ScanParameters                  matlab.ui.container.Panel
        PostCollapseLabel               matlab.ui.control.Label
        VoltageRampPostCollapseButtonGroup  matlab.ui.container.ButtonGroup
        SingleVoltageImagingPostCollapseButton  matlab.ui.control.RadioButton
        RampedVoltageImagingPostCollapseButton  matlab.ui.control.RadioButton
        PreCollapseLabel                matlab.ui.control.Label
        NumField4Label                  matlab.ui.control.Label
        NumField4                       matlab.ui.control.NumericEditField
        NumField3                       matlab.ui.control.NumericEditField
        PostcollapseVoltageVLabel       matlab.ui.control.Label
        NumField2                       matlab.ui.control.NumericEditField
        NumField2Label                  matlab.ui.control.Label
        NumField1                       matlab.ui.control.NumericEditField
        NumField1Label                  matlab.ui.control.Label
        ScantypeDropDown                matlab.ui.control.DropDown
        ScantypeDropDownLabel           matlab.ui.control.Label
        StartScanButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        SaveDirectory;
        SaveName;
        VantageDirectory;
        ScanType;
        Voltages;
        P; % Matlab structure that we will populate for the DAQ script to use and scan
        Trans; % Matlab structure for transducer
    end

    methods (Access = private)

        function activate(app)
            % This function runs the Verasonics activate.p script if it
            % hasn't already been run during the current MATLAB session
            persistent called
            if isempty(called)
                cd(app.SaveDirectory);
                activate;
                called = true;
            end
        end

        function update_save(app)
            %This function updates the SaveInfolabel with the information stored in the properties.
            string_save = ['Save Location:' newline '    '];
            if valid_save_directory(app)
                string_save = [string_save fullfile(app.SaveDirectory, 'Data', [datestr(now, 'yymmdd')], app.SaveName)];
            else
                string_save = [string_save '*Not yet selected*'];
            end
            app.SaveInfoLabel.Text = string_save;
        end

        function update_ramp(app)
            %Update the Ramp Values for the Voltage Ramp and Collapse Ramp
            %options
            if strcmp(app.ScanType, 'Voltage Ramp')
                app.Voltages = app.NumField1.Value:app.NumField3.Value:app.NumField2.Value;
                app.PreCollapseLabel.Text = convertStringsToChars(strjoin(['Pre-Collapse Ramp Voltage Values:' newline string(app.Voltages)]));
                assignin('base', 'tag', app.VoltageRampPostCollapseButtonGroup.SelectedObject.Tag);
                if app.VoltageRampPostCollapseButtonGroup.SelectedObject.Tag == 'ramped' %Ramped Imaging Options
                    app.PostCollapseLabel.Text = convertStringsToChars(strjoin(['Post-Collapse Ramp Voltage Values:' newline string(app.Voltages)]));
                else %Single Voltage Imaging option
                    app.PostCollapseLabel.Text = convertStringsToChars(strjoin(['Post-Collapse Ramp Voltage Value:' newline string(max(app.Voltages))]));
                end

            elseif strcmp(app.ScanType, 'Collapse Ramp')
                app.Voltages = app.NumField1.Value:app.NumField3.Value:app.NumField2.Value;
                app.PreCollapseLabel.Text = convertStringsToChars(strjoin(['Collaspe Ramp Voltage Values:' newline string(app.Voltages)]));
            else
                app.PreCollapseLabel.Text = '';
                app.Voltages = [app.NumField3.Value];
            end
        end

        function update_scan(app)
            %Updating the scantype and ramp values
            %used for the value changed callbacks
            if valid_voltages(app)
                update_ramp(app);
            end
        end
        
        function result = valid_vantage_directory(app)
            %checks if directory selected is a valid vantage directory
            %and checks if we are in the correct version of matlab for 
            %the vantage directory selected
            result = false;
            error_title = 'Vantage Error';
            matlab_version = version('-release');
            
            %get vantage directory name
            directory_cell = strsplit(app.VantageDirectory,'/'); 
            vantage_name = directory_cell{end};
            if strcmp(matlab_version, '2021a') && ~strcmp(vantage_name, 'Vantage-4.6.2-RCH')
                raise_error(app, 'Matlab version 2021a expects directory Vantage-4.6.2-RCH', error_title)
                return
            end
            if strcmp(matlab_version, '2071b') && ~strcmp(vantage_name, 'Vantage-4.0.0-RCH')
                raise_error(app, 'Matlab version 2021a expects directory Vantage-4.0.0-RCH', error_title)
                return
            end
            result = true;
        end

        function result = valid_save_directory(app)
            %This is a helper function that is used to check if the user
            %has inputted a save directory. It returns true if the user
            %has specified a valid save directory and save name, false if they
            %have failed to provide either one
            result = false;
            save_error_title = 'SaveDirectory Error';
            if isempty(app.SaveDirectory) || isempty(app.SaveName)
                raise_error(app, 'No Save Directory Selected', save_error_title)
                return;
            end
            if ~isempty(regexp(app.SaveName, '[/\*:?"<>|]', 'once'))
                raise_error(app, 'Invalid File Name Provided', save_error_title)
                result = false;
                return;
            end
            result = true;
        end

        function result = valid_well_parameters(app)
            %This function will check if the starting well, the row
            %numbers, and the row columns are valid entries. It will return
            % true if the parameters are valid, false otherwise alongside
            % an error message
            start_well = app.StartingwellEditField.Value;
            error_msg = [start_well ' is an invalid starting well value'];
            error_title = 'Well Parameters error';
            result = false; %initialize result to false, it will change to true when all the tests are passed

            %Check first character to see if it is valid character for the
            %row
            if ~ismember(start_well(1), 'ABCDEFGH')
                result = false;
                raise_error(app, error_msg, error_title);
                return;
            end

            %check the the column number to see if it is valid
            start_column_num = str2double(start_well(2:end));
            if isnan(start_column_num)
                raise_error(app, error_msg, error_title);
                return
            end
            if ~ismember(start_column_num, 1:1:12)
                raise_error(app, error_msg, error_title);
                return
            end

            %check the number of columns that will be used to see if it is
            %valid
            num_columns = app.NumberofcolumnsEditField.Value;
            num_rows = app.NumberofrowsEditField.Value;
            if ~ismember(num_columns, 1:1:13 - start_column_num)
                raise_error(app, 'Invalid Number of Columns', error_title);
                return
            end

            %check the number of rows that will be used to see if it is
            %valid
            start_row_num = find('ABCDEFGH'==start_well(1));
            if ~ismember(num_rows, 1:1:9 - start_row_num)
                raise_error(app, 'Invalid Number of Rows', error_title);
                return
            end
            result = true;
        end

        function result = valid_voltages(app)
            %This function will raise an error and return false if there
            %are any invalid voltage entries. Otherwise, it will return
            %true
            result = false; %initialize result to false, it will change to true when all the tests are passed
            voltage_error_title = 'Invalid Voltage Error';

            %Raise an error if any of the voltages are negative
            if app.NumField1.Value < 0
                raise_error(app, [app.NumField1.Value ' is an invalid voltage value'], voltage_error_title);
                return;
            end
            if app.NumField2.Value < 0
                raise_error(app, [app.NumField2.Value ' is an invalid voltage value'], voltage_error_title);
                return;
            end
            if app.NumField3.Value < 0
                raise_error(app, [app.NumField3.Value ' is an invalid voltage value'], voltage_error_title);
                return;
            end
            if app.NumField4.Value < 0
                raise_error(app, [app.NumField4.Value ' is an invalid voltage value'], voltage_error_title);
                return;
            end

            max_voltage = max([app.NumField1.Value, app.NumField2.Value, app.NumField3.Value]);

            %checking voltage for the ramp options
            if (strcmp(app.ScanType, 'Voltage Ramp') || strcmp(app.ScanType, 'Collapse Ramp'))
                %checks if the starting voltage is greater than the final
                if (app.NumField1.Value > app.NumField2.Value)
                    raise_error(app, 'Invalid Start and Final Voltage values', voltage_error_title);
                    return
                end
                %check that none of the voltages except Step is less than 1.6 V
                if (app.NumField1.Value < 1.6 || app.NumField2.Value < 1.6 || app.NumField4.Value < 1.6)
                    raise_error(app, 'None of the voltages except Step should be less than 1.6 V', voltage_error_title);
                    return
                end

                %include number field 4 in max voltage calculation
                max_voltage = max([max_voltage, app.NumField4.Value]);
            end

            %checking the max voltage based on the transducer selected
            if ((app.L2214vButton.Value == true) && (max_voltage > 25))
                raise_error(app, 'voltages for the L22-14v transducer must be at most 25V', voltage_error_title);
                return
            end
            if ((app.L104vButton.Value == true) && (max_voltage > 50))
                raise_error(app, 'voltages for the L10-4 transducer must be at most 50V', voltage_error_title);
                return
            end
            if ((app.L2214vXButton.Value == true) && (max_voltage > 30))
                raise_error(app, 'voltages for the L22-14vX transducer must be at most 30V', voltage_error_title);
                return
            end
            result = true;
        end

        function result = valid_scan_replicate_parameters(app)
            %This function will raise an error and return false if there
            %are any invalid replicate scan parameter entries. Otherwise,
            % it will return true
            result = false;
            rep_error_title = 'Invalid scan replicate error';
            start_row = app.Startrowofplates.Value;
            n_rows = app.Numberofrowsofplates.Value;
            start_col = app.Startcolumnofplates.Value;
            n_cols = app.Numberofcolumnsofplates.Value;
            if n_rows + start_row > 4
                raise_error(app, 'Multiple Plate Parameters: Invalid row number of plates scanned. Max of 3 plates can be scanned starting from 1', rep_error_title);
                return
            end
            if n_cols + start_col > 5
                raise_error(app, 'Multiple Plate Parameters: Invalid column number of plates scanned. Max of 4 plates can be scanned starting from 1', rep_error_title);
                return
            end
            result = true;
        end
        
        function result = valid_stage_connection(app)
            %This function checks if the
            result = false;
            %there is a workspace variable called stage_initialize_error
            stage_error = evalin('base','stage_initialize_error');
            if stage_error == 1
                raise_error(app, 'Could not connect to motor stage', 'Stage Initialization Error')
                return;
            end
            result = true;
        end

        function raise_error(app, message, title)
            %This is a helper function that opens up a new window and displays error messages to the user
            uialert(app.AcousticPlateReaderUIFigure, message, title);
            %             h=errordlg(message,'Error');
            %             set(h, 'WindowStyle', 'modal');
            %             uiwait(h);
        end

        function update_default_voltages(app)
            %This function will change the edit fields for the voltage
            %depending on what option is selected in the Scan Type Drop
            %Down and the transducer
            %checking the max voltage based on the transducer selected
            max_voltage = 25; %defaults button selected is L2214v
            if app.L104vButton.Value == true
                max_voltage = 50;
            elseif app.L2214vXButton.Value == true
                max_voltage = 30;
            end

            app.ScanType = app.ScantypeDropDown.Value;
            if strcmp(app.ScanType,'Pre-/Post-Collapse')
                app.NumField1Label.Text = 'Pre-collapse Voltage (V)';
                app.NumField1.Value = max_voltage;
                app.NumField2Label.Text = 'Collapse Voltage (V)';
                app.NumField2.Value = max_voltage;
                app.PostcollapseVoltageVLabel.Text = 'Post-collapse Voltage (V)';
                app.NumField3.Value = max_voltage;
                app.NumField4.Visible = false; %this isn't working??
                app.NumField4Label.Visible = false; %this isn't working, check syntax!!
                app.PostCollapseLabel.Visible = false;
                app.VoltageRampPostCollapseButtonGroup.Visible = false; %this isn't working!! check syntax
            elseif strcmp(app.ScanType,'Voltage Ramp') || strcmp(app.ScanType,'Collapse Ramp')
                app.NumField1Label.Text = 'Start Voltage (V)';
                app.NumField1.Value = 2;
                app.NumField2Label.Text = 'Final Voltage (V)';
                app.NumField2.Value = max_voltage;
                app.PostCollapseLabel.Visible = true;
                app.PostcollapseVoltageVLabel.Text = 'Step (V)';
                app.NumField3.Value = 1;
                app.NumField4.Visible = true;
                app.NumField4Label.Visible = true;
                if strcmp(app.ScanType,'Voltage Ramp')
                    app.NumField4Label.Text = 'Collapse Voltage (V)';
                    app.NumField4.Value = max_voltage;
                    app.VoltageRampPostCollapseButtonGroup.Visible = true;
                end
                if strcmp(app.ScanType,'Collapse Ramp')
                    app.NumField4Label.Text = 'Imaging Voltage (V)';
                    app.NumField4.Value = 1.6;
                    app.VoltageRampPostCollapseButtonGroup.Visible = false;
                end
            end
            update_ramp(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScanType = app.ScantypeDropDown.Value;
        end

        % Value changed function: ScantypeDropDown
        function ScantypeDropDownValueChanged(app, event)
            update_default_voltages(app);
            update_scan(app);
        end

        % Callback function
        function UpdateScanPushed(app, event)
            if valid_voltages(app)
                app.ScanType = app.ScantypeDropDown.Value;
                update_ramp(app);
            end
        end

        % Button pushed function: SelectVantagedirectoryButton
        function SelectVantagedirectoryButtonPushed(app, event)
            [app.VantageDirectory , ] = uigetdir;
            app.VantageDirectory = app.SaveDirectory;
            drawnow;
            figure(app.AcousticPlateReaderUIFigure)
            update_save(app);
        end

        % Button pushed function: StartScanButton
        function StartScanButtonPushed(app, event)
            %Start Scan button is pushed, button becomes deactivated once
            %pushed
            %check if information provided is valid
            if ~valid_well_parameters(app)
                return
            end
            if ~valid_save_directory(app)
                return
            end
            if ~valid_voltages(app)
                return
            end
            if ~valid_scan_replicate_parameters(app)
                return
            end
            activate(app); % change to and activate Vantage directory

            Initial_Pos = app.StartingwellEditField.Value; %Starting point

            app.P.savePath = [fullfile(app.SaveDirectory, 'Data', datestr(now, 'yymmdd')) '/']; % dir for scan
            app.P.saveDirName = app.SaveName; % dir name
            app.P.saveDirName1 = [datestr(now, 'yymmdd') '_']; % dir prefix
            app.P.seed = app.Voltages; % Voltge ramp range
            app.P.scantype = app.ScanType; % Provide scantype
            %replicate parameters
            app.P.pNum = [app.NumberofrowsEditField.Value app.NumberofcolumnsEditField.Value];
            Initial_PlatePos = [app.Startrowofplates.Value app.Startcolumnofplates.Value];

            app.Trans.name = app.TransducerButtonGroup.SelectedObject.Text; %set the value of Trans.name

            % Specify scan parameters
            if strcmp(app.ScanType,'Pre-post Collapse')
                %imaging done with axicon beams
                PostCollapse = app.NumField3.Value; % post-collapse voltage
                app.P.BC = app.NumField2.Value; % Parabolic collapse voltage % max(P.seed, 25)
                app.P.BI = 1.6; % Parabolic B-mode imaging voltage
            elseif strcmp(app.ScanType,'Voltage Ramp')
                %imaging done with axicon beams
                PostCollapse = max(app.P.seed); % post-collapse voltage
                app.P.BC = app.NumField4.Value; % Parabolic collapse voltage
                app.P.BI = 1.6; % Parabolic B-mode imaging voltage
                %Post Collapse Imaging Type Handling
                if app.SingleVoltageImagingPostCollapseButton.Value == 1
                    assignin('base',"zz_PostCollapseImagingType", "ramped");
                else
                    assignin('base',"zz_PostCollapseImagingType", "single");
                end
            elseif strcmp(app.ScanType,'Collapse Ramp')
                %Imaging is done with parabolic beams
                app.P.BC = 25; % Parabolic collapse voltage, compare
                app.P.BI = app.NumField4.Value; % Parabolic B-mode imaging voltage
                PostCollapse = app.P.BI; %Not used, but set it the same as P.BI
            end

            %setting the beamtype
            %changing the beam type sets the value of P.RampMode in the following way: 1 = pBmode, 2 = xAM, 3 = xBmode, 4 = pAM
            if (app.pBmodeButton.Value == true)
                app.P.RampMode = 1;
            end
            if (app.xAMButton.Value == true)
                app.P.RampMode = 2;
            end
            if (app.xBmodeButton.Value == true)
                app.P.RampMode = 3;
            end
            if (app.pAMButton.Value == true)
                app.P.RampMode = 4;
            end

            app.P.xLines = app.NumberofrowsEditField.Value; % number of scanned lines in the x-direction
            app.P.zLines = app.NumberofcolumnsEditField.Value; % number of scanned lines in the z-direction
            app.P.rc_threshold = app.EquilibrationFramesEditField.Value; %Number of frames taken after voltage

            % Clear base workspace, then assign variables into it
            evalin('base', 'clear');
            assignin('base', "Initial_Pos", Initial_Pos);
            assignin('base', "Initial_PlatePos", Initial_PlatePos);
            assignin('base', "P", app.P);
            assignin('base', "Trans", app.Trans);
            assignin('base', "PostCollapse", PostCollapse);

            app.StartScanButton.Text = 'Running Scan';
            app.StartScanButton.Enable = false;
            app.StopScanReturntoOriginButton.Visible = 1;
            
            %initialize stage
            params = evalin('base', "sub_AllSettings('ZJScanTest')");
            params = evalin('base', "sub_Stage_Initialize(params)");
            if ~valid_stage_connection(app)
                %if not connection to stage is established, raise error
                return
            end
            assignin('base','params',params);
            evalin('base', 'DAQ_GUI_compatible'); % call data acquisition script
            app.StartScanButton.Text = 'Scan Complete';
        end

        % Callback function
        function UpdateSaveButtonPushed(app, event)
            update_save(app);
        end

        % Value changed function: NumField1
        function NumField1ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: NumField2
        function NumField2ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: NumField3
        function NumField3ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: NumField4
        function NumField4ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: Startrowofplates
        function StartrowofplatesValueChanged(app, event)
            value = app.Startrowofplates.Value;
            if value < 1 || value > 3
                raise_error(app, 'Invalid Starting Row value', 'Plate Parameters Error');
            end
        end

        % Value changed function: Numberofrowsofplates
        function NumberofrowsofplatesValueChanged(app, event)
            value = app.Numberofrowsofplates.Value;
            if value < 1 || value > 3
                raise_error(app, 'Invalid Number of Rows value', 'Plate Parameters Error');
            end
        end

        % Value changed function: Startcolumnofplates
        function StartcolumnofplatesValueChanged(app, event)
            value = app.Startcolumnofplates.Value;
            if value < 1 || value > 4
                raise_error(app, 'Invalid Starting Column', 'Plate Parameters Error');
            end
        end

        % Value changed function: Numberofcolumnsofplates
        function NumberofcolumnsofplatesValueChanged(app, event)
            value = app.Numberofcolumnsofplates.Value;
            if value < 1 || value > 4
                raise_error(app, 'Invalid Number of Columns value', 'Plate Parameters Error');
            end
        end

        % Value changed function: EquilibrationFramesEditField
        function EquilibrationFramesEditFieldValueChanged(app, event)
            value = app.EquilibrationFramesEditField.Value;
            if value < 2
                raise_error(app, 'Invalid Number of Equilibration frames, should be more than 2', 'Equilibration frames error');
            end
        end

        % Selection changed function: 
        % VoltageRampPostCollapseButtonGroup
        function VoltageRampPostCollapseButtonGroupSelectionChanged(app, event)
            update_ramp(app);
        end

        % Button pushed function: StopScanReturntoOriginButton
        function StopScanReturntoOriginButtonPushed(app, event)
            %stop scan and calls return to origin script
            %             evalin('base',"error('Scan stopped by user')");
            %             evalin('base','BackToOrigin');
            %             evalin('base','Release_Stage');
        end

        % Selection changed function: TransducerButtonGroup
        function TransducerButtonGroupSelectionChanged(app, event)
            update_default_voltages(app);
        end

        % Value changed function: StartingwellEditField
        function StartingwellEditFieldValueChanged(app, event)
            valid_well_parameters(app);
        end

        % Value changed function: NumberofrowsEditField
        function NumberofrowsEditFieldValueChanged(app, event)
            valid_well_parameters(app);
        end

        % Value changed function: NumberofcolumnsEditField
        function NumberofcolumnsEditFieldValueChanged(app, event)
            valid_well_parameters(app);
        end

        % Button pushed function: UploadpresetButton
        function UploadpresetButtonPushed(app, event)
            %set presets based on the imported .mat file
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                %if no file is provided, then return
                return
            end
            try
                uploaded = load(fullfile(path,file), "-mat",'preset');
            catch
                raise_error(app, 'invalid preset uploaded','Preset Upload')
            end
            assignin("base",'uploaded',uploaded)
            preset = uploaded.preset;

            app.SaveDirectory = preset.SaveDirectory;
            app.SaveName = preset.SaveName;
            app.SavenameEditField.Value = app.SaveName;
            update_save(app);

            %set the Scantype from the preset and update the app
            app.ScantypeDropDown.Value = preset.ScanType;
            update_default_voltages(app);
            update_scan(app);
            
            %button type group for voltage ramp
            app.RampedVoltageImagingPostCollapseButton.Value = preset.ramped_bool;
            app.SingleVoltageImagingPostCollapseButton.Value = preset.single_bool;

            %update Transducer
            app.L2214vXButton.Value = preset.L2214vX_bool;
            app.L2214vButton.Value = preset.L2214v_bool;
            app.L104vButton.Value = preset.L104v_bool;

            %update beam type
            app.xAMButton.Value = preset.xAM_bool;
            app.xBmodeButton.Value = preset.xBmode_bool;
            app.pAMButton.Value = preset.pAM_bool;
            app.pBmodeButton.Value = preset.pBmode_bool;

            %save well params
            app.StartingwellEditField.Value = preset.startwell;
            app.NumberofcolumnsEditField.Value = preset.well_ncols;
            app.NumberofrowsEditField.Value = preset.well_nrows;

            %save plate params
            app.Startrowofplates.Value = preset.startrow;
            app.Startcolumnofplates.Value = preset.startcol;
            app.Numberofrowsofplates.Value = preset.plate_nrows;
            app.NumberofcolumnsEditField.Value = preset.plate_nrows;

            %number of equilibration frames
            app.EquilibrationFramesEditField.Value = preset.n_eqframes;
        end

        % Button pushed function: SavepresetButton
        function SavepresetButtonPushed(app, event)
            %save the presets as a .mat file specified by the user
            %check if values are valid
            if ~valid_well_parameters(app)
                return
            end
            if ~valid_save_directory(app)
                return
            end
            if ~valid_voltages(app)
                return
            end
            if ~valid_scan_replicate_parameters(app)
                return
            end
            preset = struct();
            preset.ScanType = app.ScantypeDropDown.Value;
            %save information
            preset.SaveDirectory = app.SaveDirectory;
            preset.SaveName = app.SaveName;
            
            %Voltage ramp imaging options
            preset.ramped_bool = app.RampedVoltageImagingPostCollapseButton.Value;
            preset.single_bool = app.SingleVoltageImagingPostCollapseButton.Value;

            % Save transducer button group info
            % store boolean for each button if its checked or not
            preset.L2214vX_bool = app.L2214vXButton.Value;
            preset.L2214v_bool = app.L2214vButton.Value;
            preset.L104v_bool = app.L104vButton.Value;

            %Beam type button group
            %same store idea as tranducer button group right above
            preset.xAM_bool = app.xAMButton.Value;
            preset.xBmode_bool = app.xBmodeButton.Value;
            preset.pAM_bool = app.pAMButton.Value;
            preset.pBmode_bool = app.pBmodeButton.Value;

            %save well params
            preset.startwell = app.StartingwellEditField.Value; 
            preset.well_ncols = app.NumberofcolumnsEditField.Value;
            preset.well_nrows = app.NumberofrowsEditField.Value;

            %save plate params
            preset.startrow = app.Startrowofplates.Value;
            preset.startcol = app.Startcolumnofplates.Value;
            preset.plate_nrows = app.Numberofrowsofplates.Value;
            preset.plate_nrows = app.NumberofcolumnsEditField.Value;

            %number of equilibration frames
            preset.n_eqframes = app.EquilibrationFramesEditField.Value;

            %upload well params
            uisave({'preset'},'preset');
        end

        % Value changed function: SavenameEditField
        function SavenameEditFieldValueChanged(app, event)
            app.SaveName = app.SavenameEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcousticPlateReaderUIFigure = uifigure('Visible', 'off');
            app.AcousticPlateReaderUIFigure.Name = 'Acoustic Plate Reader';
            app.StartScanButton = uibutton(app.AcousticPlateReaderUIFigure, 'push');
            app.StartScanButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanButtonPushed, true);
            app.StartScanButton.Text = 'Start Scan';
            app.ScanParameters = uipanel(app.AcousticPlateReaderUIFigure);
            app.ScantypeDropDownLabel = uilabel(app.ScanParameters);
            app.ScantypeDropDownLabel.Text = 'Scan type:';
            app.ScantypeDropDown = uidropdown(app.ScanParameters);
            app.ScantypeDropDown.Items = {'Pre-/Post-Collapse', 'Voltage Ramp', 'Collapse Ramp'};
            app.ScantypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScantypeDropDownValueChanged, true);
            app.ScantypeDropDown.Value = 'Pre-/Post-Collapse';
            app.NumField1Label = uilabel(app.ScanParameters);
            app.NumField1Label.Text = 'Pre-Collapse Voltage (V)';
            app.NumField1 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField1.ValueChangedFcn = createCallbackFcn(app, @NumField1ValueChanged, true);
            app.NumField1.Value = 25;
            app.NumField2Label = uilabel(app.ScanParameters);
            app.NumField2Label.Text = 'Collapse Voltage (V)';
            app.NumField2 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField2.ValueChangedFcn = createCallbackFcn(app, @NumField2ValueChanged, true);
            app.NumField2.Value = 25;
            app.PostcollapseVoltageVLabel = uilabel(app.ScanParameters);
            app.PostcollapseVoltageVLabel.Text = 'Post-collapse Voltage (V)';
            app.NumField3 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField3.ValueChangedFcn = createCallbackFcn(app, @NumField3ValueChanged, true);
            app.NumField3.Value = 25;
            app.NumField4 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField4.ValueChangedFcn = createCallbackFcn(app, @NumField4ValueChanged, true);
            app.NumField4.Visible = 'off';
            app.NumField4.Value = 25;
            app.NumField4Label = uilabel(app.ScanParameters);
            app.NumField4Label.Visible = 'off';
            app.NumField4Label.Text = 'Imaging Voltage (V)';
            app.PreCollapseLabel = uilabel(app.ScanParameters);
            app.PreCollapseLabel.Text = '';
            app.VoltageRampPostCollapseButtonGroup = uibuttongroup(app.ScanParameters);
            app.VoltageRampPostCollapseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VoltageRampPostCollapseButtonGroupSelectionChanged, true);
            app.VoltageRampPostCollapseButtonGroup.Title = 'Voltage Ramp Image Options';
            app.VoltageRampPostCollapseButtonGroup.Visible = 'off';
            app.VoltageRampPostCollapseButtonGroup.Tag = 'ramped';
            app.RampedVoltageImagingPostCollapseButton = uiradiobutton(app.VoltageRampPostCollapseButtonGroup);
            app.RampedVoltageImagingPostCollapseButton.Tag = 'ramped';
            app.RampedVoltageImagingPostCollapseButton.Text = {'Ramped Voltage Imaging '; ' Post Collapse'};
            app.RampedVoltageImagingPostCollapseButton.Value = true;
            app.SingleVoltageImagingPostCollapseButton = uiradiobutton(app.VoltageRampPostCollapseButtonGroup);
            app.SingleVoltageImagingPostCollapseButton.Tag = 'single';
            app.SingleVoltageImagingPostCollapseButton.Text = {'Single Voltage Imaging'; 'Post Collapse'};
            app.PostCollapseLabel = uilabel(app.ScanParameters);
            app.PostCollapseLabel.Text = '';
            app.SaveFileDirectory = uipanel(app.AcousticPlateReaderUIFigure);
            app.SaveInfoLabel = uilabel(app.SaveFileDirectory);
            app.SaveInfoLabel.Text = {'Save location:'; '    *Not yet selected*'; ''; ''};
            app.SavenameLabel = uilabel(app.SaveFileDirectory);
            app.SavenameLabel.Text = 'Save name:';
            app.SavenameEditField = uieditfield(app.SaveFileDirectory, 'text');
            app.SavenameEditField.ValueChangedFcn = createCallbackFcn(app, @SavenameEditFieldValueChanged, true);
            app.SelectVantagedirectoryButton = uibutton(app.SaveFileDirectory, 'push');
            app.SelectVantagedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVantagedirectoryButtonPushed, true);
            app.SelectVantagedirectoryButton.Text = 'Select Vantage directory';
            app.Vantage440462supportedLabel = uilabel(app.SaveFileDirectory);
            app.Vantage440462supportedLabel.Text = 'Vantage 4.4.0 - 4.6.2 supported';
            app.SavepresetButton = uibutton(app.SaveFileDirectory, 'push');
            app.SavepresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavepresetButtonPushed, true);
            app.SavepresetButton.Text = 'Save preset';
            app.UploadpresetButton = uibutton(app.SaveFileDirectory, 'push');
            app.UploadpresetButton.ButtonPushedFcn = createCallbackFcn(app, @UploadpresetButtonPushed, true);
            app.UploadpresetButton.Text = 'Upload preset';
            app.PlateParameters = uipanel(app.AcousticPlateReaderUIFigure);
            app.NumberofrowsLabel = uilabel(app.PlateParameters);
            app.NumberofrowsLabel.Text = 'Number of rows';
            app.NumberofrowsEditField = uieditfield(app.PlateParameters, 'numeric');
            app.NumberofrowsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofrowsEditFieldValueChanged, true);
            app.NumberofrowsEditField.Value = 8;
            app.NumberofcolumnsEditFieldLabel = uilabel(app.PlateParameters);
            app.NumberofcolumnsEditFieldLabel.Text = 'Number of columns';
            app.NumberofcolumnsEditField = uieditfield(app.PlateParameters, 'numeric');
            app.NumberofcolumnsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofcolumnsEditFieldValueChanged, true);
            app.NumberofcolumnsEditField.Value = 12;
            app.StartingwellEditFieldLabel = uilabel(app.PlateParameters);
            app.StartingwellEditFieldLabel.Text = 'Starting well';
            app.StartingwellEditField = uieditfield(app.PlateParameters, 'text');
            app.StartingwellEditField.ValueChangedFcn = createCallbackFcn(app, @StartingwellEditFieldValueChanged, true);
            app.StartingwellEditField.Value = 'A1';
            app.IndividualPlateParametersLabel = uilabel(app.PlateParameters);
            app.IndividualPlateParametersLabel.Text = 'Individual Plate Parameters';
            app.BeamTypeButtonGroup = uibuttongroup(app.AcousticPlateReaderUIFigure);
            app.BeamTypeButtonGroup.Title = 'Beam Type';
            app.pBmodeButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.pBmodeButton.Text = 'pBmode';
            app.xBmodeButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.xBmodeButton.Text = 'xBmode';
            app.pAMButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.pAMButton.Text = 'pAM';
            app.xAMButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.xAMButton.Text = 'xAM';
            app.xAMButton.Value = true;
            app.TransducerButtonGroup = uibuttongroup(app.AcousticPlateReaderUIFigure);
            app.TransducerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TransducerButtonGroupSelectionChanged, true);
            app.TransducerButtonGroup.Title = 'Transducer';
            app.L2214vButton = uiradiobutton(app.TransducerButtonGroup);
            app.L2214vButton.Text = 'L22-14v';
            app.L104vButton = uiradiobutton(app.TransducerButtonGroup);
            app.L104vButton.Text = 'L10-4v';
            app.L2214vXButton = uiradiobutton(app.TransducerButtonGroup);
            app.L2214vXButton.Text = 'L22-14vX';
            app.L2214vXButton.Value = true;
            app.ReplicateParameters_2 = uipanel(app.AcousticPlateReaderUIFigure);
            app.StartingrowLabel = uilabel(app.ReplicateParameters_2);
            app.StartingrowLabel.Text = 'Starting row';
            app.Startrowofplates = uieditfield(app.ReplicateParameters_2, 'numeric');
            app.Startrowofplates.Limits = [0 Inf];
            app.Startrowofplates.ValueChangedFcn = createCallbackFcn(app, @StartrowofplatesValueChanged, true);
            app.Startrowofplates.Value = 1;
            app.StartingcolumnLabel = uilabel(app.ReplicateParameters_2);
            app.StartingcolumnLabel.Text = 'Starting column';
            app.Startcolumnofplates = uieditfield(app.ReplicateParameters_2, 'numeric');
            app.Startcolumnofplates.Limits = [0 Inf];
            app.Startcolumnofplates.ValueChangedFcn = createCallbackFcn(app, @StartcolumnofplatesValueChanged, true);
            app.Startcolumnofplates.Value = 1;
            app.MultiplePlateParametersLabel = uilabel(app.ReplicateParameters_2);
            app.MultiplePlateParametersLabel.Text = 'Multiple Plate Parameters';
            app.NumberofrowsLabel_2 = uilabel(app.ReplicateParameters_2);
            app.NumberofrowsLabel_2.Text = 'Number of rows';
            app.Numberofrowsofplates = uieditfield(app.ReplicateParameters_2, 'numeric');
            app.Numberofrowsofplates.Limits = [0 Inf];
            app.Numberofrowsofplates.ValueChangedFcn = createCallbackFcn(app, @NumberofrowsofplatesValueChanged, true);
            app.Numberofrowsofplates.Value = 3;
            app.NumberofcolumnsEditField_2Label_2 = uilabel(app.ReplicateParameters_2);
            app.NumberofcolumnsEditField_2Label_2.Text = 'Number of columns';
            app.Numberofcolumnsofplates = uieditfield(app.ReplicateParameters_2, 'numeric');
            app.Numberofcolumnsofplates.Limits = [0 Inf];
            app.Numberofcolumnsofplates.ValueChangedFcn = createCallbackFcn(app, @NumberofcolumnsofplatesValueChanged, true);
            app.Numberofcolumnsofplates.Value = 4;
            app.NEquilibrationFrames = uipanel(app.AcousticPlateReaderUIFigure);
            app.NumberofEquilibrationFramesLabel = uilabel(app.NEquilibrationFrames);
            app.NumberofEquilibrationFramesLabel.Text = {'Number of Equilibration '; 'Frames'};
            app.EquilibrationFramesEditField = uieditfield(app.NEquilibrationFrames, 'numeric');
            app.EquilibrationFramesEditField.Limits = [0 Inf];
            app.EquilibrationFramesEditField.ValueChangedFcn = createCallbackFcn(app, @EquilibrationFramesEditFieldValueChanged, true);
            app.EquilibrationFramesEditField.Value = 5;
            app.StopScanReturntoOriginButton = uibutton(app.AcousticPlateReaderUIFigure, 'push');
            app.StopScanReturntoOriginButton.ButtonPushedFcn = createCallbackFcn(app, @StopScanReturntoOriginButtonPushed, true);
            app.StopScanReturntoOriginButton.Visible = 'off';
            app.StopScanReturntoOriginButton.Text = 'Stop Scan, Return to Origin';
            % Show the figure after all components are created
            app.AcousticPlateReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Acquisition_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcousticPlateReaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcousticPlateReaderUIFigure)
        end
    end
end