classdef Data_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        SelectFileTab          matlab.ui.container.Tab
        UpdateButton           matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        ScanTypeDropDownLabel  matlab.ui.control.Label
        ScanTypeDropDown       matlab.ui.control.DropDown
        NumField1Label         matlab.ui.control.Label
        NumField1              matlab.ui.control.NumericEditField
        NumField2              matlab.ui.control.NumericEditField
        NumField3              matlab.ui.control.NumericEditField
        NumField4              matlab.ui.control.NumericEditField
        NumField4Label         matlab.ui.control.Label
        NumField3Label         matlab.ui.control.Label
        NumField2Label         matlab.ui.control.Label
        ConfirmButton          matlab.ui.control.Button
        Panel_2                matlab.ui.container.Panel
        SelectDirectoryButton  matlab.ui.control.Button
        ImportmetadataButton   matlab.ui.control.Button
        InfoLabel              matlab.ui.control.Label
        DataVisualizationTab   matlab.ui.container.Tab
        MicroplatePlot         matlab.ui.control.UIAxes
        UITable                matlab.ui.control.Table
        VoltageRamp            matlab.ui.control.UIAxes
        GroupByDropDownLabel   matlab.ui.control.Label
        GroupByDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        ScanType;
        Data_directory = '*Not yet imported*';
        MetadataFileName = '*Not yet imported*';
        MetadataPathName = '';
        Parameters;
    end
    
    methods (Access = private)
        
        function update(app)
            %This function updates the Infolabel with the information stored in the properties.
            % It will also update the Ramp Label with the Voltages if needed
            string1 = ['Metadata File:' newline '    ' app.Data_directory];
            string2 = ['Metadata File:' newline '    ' app.MetadataPathName '\' app.MetadataFileName];
            app.InfoLabel.Text = [string1 newline string2 newline string3];
            if strcmp(app.ScanType, 'Voltage Ramp') || strcmp(app.ScanType, 'Collapse Ramp')
                app.RampLabel.Text = convertStringsToChars(strjoin(['Voltage Values:' newline string(app.NumField1.Value:app.NumField3.Value:app.NumField2.Value)]));
            end
        end
        
        function full_map = extract_metadata(app)
            %This function will extract information for each well from the
            %metadata. It will also raise errors and return a blank if
            %there are incompatability issues with the metadata file
            
            %It will return the full_map which maps well locations to
            %another map that maps parameters to values
            % example full_map {A1: {color: blue, size: large, weight: 100}, A2: {color: red, size: small, weight:50}}
        
            %file provided by the user
            if isempty(app.MetadataPathName) || isempty(app.MetadataFileName)
                return %don't proceed if the user has not provided a metadata file
            end
            metadata_cell = readcell([app.MetadataPathName '\' app.MetadataFileName]);
            size_cell = size(metadata_cell);
            wells_used = metadata_cell(2:size_cell(1)); %take all the elements in the well_used column
            full_map = containers.Map;
            
            %check that each of the wells are unique
            if length(wells_used) ~= length(unique(wells_used))
                raise_error(app, 'There exists a duplicate well');
                return;
            end
            
            transpose_metadata = metadata_cell'; %take transpose of metadata cell. Makes the code easier
            parameters = transpose_metadata(2:size_cell(2)); %take all the parameters
            app.Parameters = parameters;
            
            %iterate through the wells, check each one, and populate full map
            %The keys for full_map are strings for the wells (ex. A1), the
            %values will be maps
            start_index = size_cell(2)+2;
            for well = wells_used
                well_string = well{1}; %Have to do this so you have a string instead of a cell containing a string
                error_msg = [well_string 'is an invalid well name'];
                
                %check if the well string starts with letters A - H
                if ~ismember(well_string(1), 'ABCDEFGH')
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return;
                end
                
                %check if the well strings ends with numbers 1 - 12
                column_num = str2double(well_string(2:end));
                if isnan(column_num)
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return
                end
                if ~ismember(column_num, 1:1:12)
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return
                end
                
                %populate the map
                well_values = transpose_metadata(start_index:start_index + size_cell(2)-2);
                full_map(well_string) = containers.Map(parameters, well_values);
            end
        end
        
        function raise_error(app, message)
            %This is a helper function that opens up a new window and displays error messages to the user
            h=errordlg(message,'Error');
            set(h, 'WindowStyle', 'modal');
            uiwait(h);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ScanTypeDropDown
        function ScanTypeDropDownValueChanged(app, event)
            value = app.ScanTypeDropDown.Value;
            app.ScanType = value;
            if strcmp(value,'Pre-post Collapse')
                app.NumField1Label.Text = 'Pre-collapse Voltage (V)';
                app.NumField1.Value = 25;
                app.NumField2Label.Text = 'Collapse Voltage (V)';
                app.NumField2.Value = 25;
                app.NumField3Label.Text = 'Post-collapse Voltage (V)';
                app.NumField3.Value = 25;
                app.NumField4.Visible = false;
                app.NumField4Label.Visible = false;
            end
            if strcmp(value,'Voltage Ramp') || strcmp(value,'Collapse Ramp')
                app.NumField1Label.Text = 'Start Voltage (V)';
                app.NumField1.Value = 2;
                app.NumField2Label.Text = 'Final Voltage (V)';
                app.NumField2.Value = 25;
                app.NumField3Label.Text = 'Step (V)';
                app.NumField3.Value = 1;
                app.NumField4.Visible = true;
                app.NumField4Label.Visible = true;
                
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update(app);
        end

        % Callback function
        function ImportmatfileButtonPushed(app, event)
            [app.MatFileNames, app.MatPathName] = uigetfile({'*.mat'},'Select Data Files to Use','MultiSelect','on');  
            if ischar(app.MatFileNames) 
                app.MatFileNames={app.MatFileNames}; 
            end 
            update(app);
        end

        % Button pushed function: ImportmetadataButton
        function ImportmetadataButtonPushed(app, event)
            if extract_metadata(app) ~= containers.Map
                [app.MetadataFileName, app.MetadataPathName] = uigetfile({'*.csv';'*.xlsx';'*.xls'},'Select Data Files to Use','MultiSelect','off');
                update(app);
            end
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            [app.Data_directory, ] = uigetdir;
            update(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            imgs2processed_app(app.Data_directory, app.ScanType);
            app.GroupByDropDown.Items = [app.GroupByDropDown.Items, app.Parameters];
        end

        % Value changed function: GroupByDropDown
        function GroupByDropDownValueChanged(app, event)
            value = app.GroupByDropDown.Value;
            if strcmp(value, 'Rows')
                %event
            end
            if strcmp(value, 'Columns')
                %event
            end
        else
            %event
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectFileTab = uitab(app.TabGroup);
            app.SelectFileTab.Title = 'Select Files';
            app.UpdateButton = uibutton(app.SelectFileTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Panel = uipanel(app.SelectFileTab);
            app.ScanTypeDropDownLabel = uilabel(app.Panel);
            app.ScanTypeDropDownLabel.Text = 'Scan Type';
            app.ScanTypeDropDown = uidropdown(app.Panel);
            app.ScanTypeDropDown.Items = {'Pre-post Collapse', 'Voltage Ramp', 'Collapse Ramp'};
            app.ScanTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanTypeDropDownValueChanged, true);
            app.ScanTypeDropDown.Value = 'Pre-post Collapse';
            app.NumField1Label = uilabel(app.Panel);
            app.NumField1Label.Text = 'Pre-collapse Voltage (V)';
            app.NumField1 = uieditfield(app.Panel, 'numeric');
            app.NumField1.Value = 25;
            app.NumField2 = uieditfield(app.Panel, 'numeric');
            app.NumField2.Value = 25;
            app.NumField3 = uieditfield(app.Panel, 'numeric');
            app.NumField3.Value = 25;
            app.NumField4 = uieditfield(app.Panel, 'numeric');
            app.NumField4.Visible = 'off';
            app.NumField4.Value = 25;
            app.NumField4Label = uilabel(app.Panel);
            app.NumField4Label.Visible = 'off';
            app.NumField4Label.Text = 'Imaging Voltage (V)';
            app.NumField3Label = uilabel(app.Panel);
            app.NumField3Label.Text = 'Post-collapse Voltage (V)';
            app.NumField2Label = uilabel(app.Panel);
            app.NumField2Label.Text = 'Collapse Voltage (V)';
            app.ConfirmButton = uibutton(app.SelectFileTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Panel_2 = uipanel(app.SelectFileTab);
            app.Panel_2.Title = 'Panel';
            app.SelectDirectoryButton = uibutton(app.Panel_2, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Data Directory';
            app.ImportmetadataButton = uibutton(app.Panel_2, 'push');
            app.ImportmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmetadataButtonPushed, true);
            app.ImportmetadataButton.Text = 'Import metadata';
            app.InfoLabel = uilabel(app.Panel_2);
            app.InfoLabel.Text = {'Data Directory:'; '    *Not yet imported* '; ''; 'Metadata File:'; '    *Not yet imported* '; ''};
            app.DataVisualizationTab = uitab(app.TabGroup);
            app.DataVisualizationTab.Title = 'Data Visualization';
            app.MicroplatePlot = uiaxes(app.DataVisualizationTab);
            title(app.MicroplatePlot, 'Title')
            xlabel(app.MicroplatePlot, 'X')
            ylabel(app.MicroplatePlot, 'Y')
            app.UITable = uitable(app.DataVisualizationTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.VoltageRamp = uiaxes(app.DataVisualizationTab);
            title(app.VoltageRamp, 'Title')
            xlabel(app.VoltageRamp, 'X')
            ylabel(app.VoltageRamp, 'Y')
            app.GroupByDropDownLabel = uilabel(app.DataVisualizationTab);
            app.GroupByDropDownLabel.Text = 'Group By';
            app.GroupByDropDown = uidropdown(app.DataVisualizationTab);
            app.GroupByDropDown.Items = {'Columns', 'Rows', 'Identical Wells'};
            app.GroupByDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupByDropDownValueChanged, true);
            app.GroupByDropDown.Value = 'Columns';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Processing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end