classdef page_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportmatfileButton         matlab.ui.control.Button
        ImportcsvxlsxxslfileButton  matlab.ui.control.Button
        FilesLabel                  matlab.ui.control.Label
        ScanTypeDropDownLabel       matlab.ui.control.Label
        ScanTypeDropDown            matlab.ui.control.DropDown
        ConfirmButton               matlab.ui.control.Button
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileNames
        PathName
        ScanTypeValue
    end
    
    methods (Access = private)
        
        function print_files(app)
            app.FilesLabel.Text = append('Files:', newline);
            for file = app.FileNames
                app.FilesLabel.Text = append(app.FilesLabel.Text, app.PathName, file, newline);
            end
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportmatfileButton
        function ImportmatfileButtonPushed(app, event)
          [app.FileNames, app.PathName] = my_select_microtiter_files_mat;  
          print_files(app);
        end

        % Button pushed function: ImportcsvxlsxxslfileButton
        function ImportcsvxlsxxslfileButtonPushed(app, event)
            [app.FileNames, app.PathName] = my_select_microtiter_files;  
            print_files(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.ScanTypeValue = app.ScanTypeDropDown.Value;
            app.Label.Text = app.ScanTypeValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportmatfileButton = uibutton(app.UIFigure, 'push');
            app.ImportmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmatfileButtonPushed, true);
            app.ImportmatfileButton.Text = 'Import .mat file';
            app.ImportcsvxlsxxslfileButton = uibutton(app.UIFigure, 'push');
            app.ImportcsvxlsxxslfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportcsvxlsxxslfileButtonPushed, true);
            app.ImportcsvxlsxxslfileButton.Text = 'Import .csv/.xlsx/.xsl file';
            app.FilesLabel = uilabel(app.UIFigure);
            app.FilesLabel.Text = {'Files:'; ''};
            app.ScanTypeDropDownLabel = uilabel(app.UIFigure);
            app.ScanTypeDropDownLabel.Text = 'Scan Type';
            app.ScanTypeDropDown = uidropdown(app.UIFigure);
            app.ScanTypeDropDown.Items = {'Pre-post collapse', 'Voltage Ramp', 'Collapse Ramp'};
            app.ScanTypeDropDown.Value = 'Pre-post collapse';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end